classdef app1_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LastMeasurementsLabel    matlab.ui.control.Label
        psiEditField_2           matlab.ui.control.NumericEditField
        psiEditField_2Label      matlab.ui.control.Label
        thetaEditField_2         matlab.ui.control.NumericEditField
        thetaEditField_2Label    matlab.ui.control.Label
        phiEditField_2           matlab.ui.control.NumericEditField
        phiEditField_2Label      matlab.ui.control.Label
        HomeButton               matlab.ui.control.Button
        StopMeasuringButton      matlab.ui.control.Button
        StartMeasuringButton     matlab.ui.control.Button
        SendTrajectoryButton     matlab.ui.control.Button
        sigmaEditField           matlab.ui.control.NumericEditField
        sigmaEditFieldLabel      matlab.ui.control.Label
        SendOrientationButton    matlab.ui.control.Button
        psiEditField             matlab.ui.control.NumericEditField
        psiEditFieldLabel        matlab.ui.control.Label
        thetaEditField           matlab.ui.control.NumericEditField
        thetaEditFieldLabel      matlab.ui.control.Label
        phiEditField             matlab.ui.control.NumericEditField
        phiEditFieldLabel        matlab.ui.control.Label
        SendPositionButton       matlab.ui.control.Button
        theta_3EditField         matlab.ui.control.NumericEditField
        theta_3EditFieldLabel    matlab.ui.control.Label
        theta_2EditField         matlab.ui.control.NumericEditField
        theta_2EditFieldLabel    matlab.ui.control.Label
        theta_1EditField         matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        StateEditField           matlab.ui.control.EditField
        StateEditFieldLabel      matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        DisconnectArduinoButton  matlab.ui.control.Button
        ConnecttoArduinoButton   matlab.ui.control.Button
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduino % Description  
        nano
        microstepping % Description
        steps_per_rev
        eta11
        eta12
        eta13
        eta21
        eta22
        eta23
        alpha1
        alpha2
        beta2
        gamma2
        theta1D
        theta2D
        theta3D
        h
        h2
        h3
        stop
        done
        phi_measured
        theta_measured
        psi_measured
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Interpreter='latex';
            app.theta_2EditFieldLabel.Interpreter='latex';
            app.theta_3EditFieldLabel.Interpreter='latex';
            app.phiEditFieldLabel.Interpreter='latex';
            app.thetaEditFieldLabel.Interpreter='latex';
            app.psiEditFieldLabel.Interpreter='latex';
            app.sigmaEditFieldLabel.Interpreter='latex';
            app.UIAxes.TickLabelInterpreter='latex';
            app.UIAxes.Title.Interpreter="latex";
            app.UIAxes_2.TickLabelInterpreter='latex';
            app.UIAxes_2.Title.Interpreter="latex";
            app.UIAxes_3.TickLabelInterpreter='latex';
            app.UIAxes_3.Title.Interpreter="latex";
            app.phiEditField_2Label.Interpreter='latex';
            app.thetaEditField_2Label.Interpreter='latex';
            app.psiEditField_2Label.Interpreter='latex';
            app.microstepping=16;
            app.steps_per_rev=200;
            app.eta11=0;
            app.eta12=120;
            app.eta13=240;
            app.eta21=0;
            app.eta22=120;
            app.eta23=240;
            app.alpha1=45;
            app.alpha2=90;
            app.beta2=90;
            app.gamma2=120;
            app.phi_measured=nan;
            app.theta_measured=nan;
            app.psi_measured=nan;
            app.stop=false;
            app.done=false;
        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            try 
                app.arduino=serialport("COM5",115200);
                app.nano=serialport("COM6",115200);
                configureCallback(app.nano,'terminator',@Measure_Orientation);
                app.Lamp.Color='green';
                app.StateEditField.Value='Connected Succesfully';
            catch
                app.Lamp.Color='red';
                app.StateEditField.Value='Error Connecting';
            end


        end

        % Button pushed function: DisconnectArduinoButton
        function DisconnectArduinoButtonPushed(app, event)
            try
                app.arduino=[];
                app.nano=[];
                app.StateEditField.Value='Disconnected Succesfully';
                app.Lamp.Color='blue';
            catch
                app.StateEditField.Value='Error Disconnecting';
                app.Lamp.Color='red';
            end
        end

        % Button pushed function: SendPositionButton
        function SendPositionButtonPushed(app, event)
            steps1=app.theta1D*((app.microstepping*app.steps_per_rev)/360);
            steps2=app.theta2D*((app.microstepping*app.steps_per_rev)/360);
            steps3=app.theta3D*((app.microstepping*app.steps_per_rev)/360);
            tstring=['M ','A',num2str(steps1),' B',num2str(steps2),' C',num2str(steps3)];
            %tstring="ON";
            write(app.arduino,tstring,'char');
            app.Lamp.Color='magenta';
        end

        % Button pushed function: SendOrientationButton
        function SendOrientationButtonPushed(app, event)
            phi=app.phiEditField.Value;
            theta=app.thetaEditField.Value;
            psi=app.psiEditField.Value;
            [theta_input]=inverse_kinematics(app.alpha1,app.eta11,app.eta12,app.eta13,app.alpha2,app.beta2,phi,theta,psi);
            steps1=theta_input(1)*((app.microstepping*app.steps_per_rev)/360);
            steps2=theta_input(2)*((app.microstepping*app.steps_per_rev)/360);
            steps3=theta_input(3)*((app.microstepping*app.steps_per_rev)/360);
            tstring=['M ','A',num2str(steps1),' B',num2str(steps2),' C',num2str(steps3)];
            %tstring="ON";
            write(app.arduino,tstring,'char')
            app.Lamp.Color='green';
            %app.theta_1EditField.Value=theta_input(1);
            %app.theta_2EditField.Value=theta_input(2);
            %app.theta_3EditField.Value=theta_input(3);
            
        end

        % Value changed function: theta_1EditField
        function theta_1EditFieldValueChanged(app, event)
            value = app.theta_1EditField.Value;
            app.theta1D=value;
        end

        % Value changed function: theta_2EditField
        function theta_2EditFieldValueChanged(app, event)
            value = app.theta_2EditField.Value;
            app.theta2D=value;
        end

        % Value changed function: theta_3EditField
        function theta_3EditFieldValueChanged(app, event)
            value = app.theta_3EditField.Value;
            app.theta3D=value;
        end

        % Button pushed function: StartMeasuringButton
        function StartMeasuringButtonPushed(app, event)
            
            app.h=animatedline(app.UIAxes);
            app.h2=animatedline(app.UIAxes_2);
            app.h3=animatedline(app.UIAxes_3);
            startTime=datetime('now');
            while ~app.stop
                if numel(app.nano.UserData) >= 3
                    % Read data from UserData of the serialport object
                    app.phi_measured = app.nano.UserData(1);
                    app.theta_measured = app.nano.UserData(2);
                    app.psi_measured = app.nano.UserData(3);
                end                
                t=datetime('now')-startTime;
                addpoints(app.h,datenum(t),app.phi_measured);
                addpoints(app.h2,datenum(t),app.theta_measured);
                addpoints(app.h3,datenum(t),app.psi_measured);
                drawnow
                app.stop=app.done;
                app.phiEditField_2.Value=app.phi_measured;
                app.thetaEditField_2.Value=app.theta_measured;
                app.psiEditField_2.Value=app.psi_measured;
            end
        end

        % Button pushed function: StopMeasuringButton
        function StopMeasuringButtonPushed(app, event)
            app.done=true;            
        end

        % Button pushed function: SendTrajectoryButton
        function SendTrajectoryButtonPushed(app, event)
            phi=app.phiEditField.Value;
            theta=app.thetaEditField.Value;
            psi=app.psiEditField.Value;
            s=app.sigmaEditField.Value;
            sigma=linspace(0,s,15);
            [theta_traj]=Infinite_Rotational_Motion2(phi,theta,psi,app.alpha1,app.alpha2,app.beta2,app.eta11,app.eta12,app.eta13,app.eta21,app.eta22,app.eta23,sigma);
            steps1=theta_traj(1,:)*((app.microstepping*app.steps_per_rev)/360);
            steps2=theta_traj(2,:)*((app.microstepping*app.steps_per_rev)/360);
            steps3=theta_traj(3,:)*((app.microstepping*app.steps_per_rev)/360);
            tstring='I';
            for i=1:length(steps1)
                tstring=[tstring,',','M',' A',num2str((steps1(i))),' B',num2str((steps2(i))),' C',num2str((steps3(i)))];
            end
            write(app.arduino,tstring,'char');
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            tstring=['M ','A',num2str(0),' B',num2str(0),' C',num2str(0)];
            write(app.arduino,tstring,'char');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Angle $\phi$', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'Time (s)', 'Interpreter', 'latex')
            ylabel(app.UIAxes, '$\phi$ (deg)', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            app.UIAxes.XLim = [0 Inf];
            app.UIAxes.YLim = [-300 Inf];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Angle $\theta$', 'Interpreter', 'latex')
            xlabel(app.UIAxes_2, 'Time (s)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_2, '$\theta$ (deg)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_2, 'Z', 'Interpreter', 'latex')
            app.UIAxes_2.XLim = [0 Inf];
            app.UIAxes_2.YLim = [-50 50];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Angle $\psi$', 'Interpreter', 'latex')
            xlabel(app.UIAxes_3, 'Time (s)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_3, '$\psi$ (deg)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_3, 'Z', 'Interpreter', 'latex')
            app.UIAxes_3.XLim = [0 Inf];
            app.UIAxes_3.YLim = [-50 50];
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.DisconnectArduinoButton = uibutton(app.UIFigure, 'push');
            app.DisconnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectArduinoButtonPushed, true);
            app.DisconnectArduinoButton.Text = 'Disconnect Arduino';
            app.Lamp = uilamp(app.UIFigure);
            app.StateEditFieldLabel = uilabel(app.UIFigure);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '$\theta_{1}$';
            app.theta_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta_1EditField.ValueChangedFcn = createCallbackFcn(app, @theta_1EditFieldValueChanged, true);
            app.theta_2EditFieldLabel = uilabel(app.UIFigure);
            app.theta_2EditFieldLabel.Text = '$\theta_{2}$';
            app.theta_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta_2EditField.ValueChangedFcn = createCallbackFcn(app, @theta_2EditFieldValueChanged, true);
            app.theta_3EditFieldLabel = uilabel(app.UIFigure);
            app.theta_3EditFieldLabel.Text = '$\theta_{3}$';
            app.theta_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta_3EditField.ValueChangedFcn = createCallbackFcn(app, @theta_3EditFieldValueChanged, true);
            app.SendPositionButton = uibutton(app.UIFigure, 'push');
            app.SendPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SendPositionButtonPushed, true);
            app.SendPositionButton.Text = 'Send Position';
            app.phiEditFieldLabel = uilabel(app.UIFigure);
            app.phiEditFieldLabel.Text = '$\phi$';
            app.phiEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetaEditFieldLabel = uilabel(app.UIFigure);
            app.thetaEditFieldLabel.Text = '$\theta$';
            app.thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.psiEditFieldLabel = uilabel(app.UIFigure);
            app.psiEditFieldLabel.Text = '$\psi$';
            app.psiEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendOrientationButton = uibutton(app.UIFigure, 'push');
            app.SendOrientationButton.ButtonPushedFcn = createCallbackFcn(app, @SendOrientationButtonPushed, true);
            app.SendOrientationButton.Text = 'Send Orientation';
            app.sigmaEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaEditFieldLabel.Text = '$\sigma$';
            app.sigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.SendTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SendTrajectoryButtonPushed, true);
            app.SendTrajectoryButton.Text = 'Send Trajectory';
            app.StartMeasuringButton = uibutton(app.UIFigure, 'push');
            app.StartMeasuringButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasuringButtonPushed, true);
            app.StartMeasuringButton.Text = 'Start Measuring';
            app.StopMeasuringButton = uibutton(app.UIFigure, 'push');
            app.StopMeasuringButton.ButtonPushedFcn = createCallbackFcn(app, @StopMeasuringButtonPushed, true);
            app.StopMeasuringButton.Text = 'Stop Measuring';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.phiEditField_2Label = uilabel(app.UIFigure);
            app.phiEditField_2Label.Text = '$\phi$';
            app.phiEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.thetaEditField_2Label = uilabel(app.UIFigure);
            app.thetaEditField_2Label.Text = '$\theta$';
            app.thetaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.psiEditField_2Label = uilabel(app.UIFigure);
            app.psiEditField_2Label.Text = '$\psi$';
            app.psiEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LastMeasurementsLabel = uilabel(app.UIFigure);
            app.LastMeasurementsLabel.Text = 'Last Measurements';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end